classdef text_recent_prj < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FileMenu               matlab.ui.container.Menu
        NewProjectMenu         matlab.ui.container.Menu
        OpenProjectMenu        matlab.ui.container.Menu
        OpenRecentProjectMenu  matlab.ui.container.Menu
        SaveProjectMenu        matlab.ui.container.Menu
        SaveasMenu             matlab.ui.container.Menu
        ExitMenu               matlab.ui.container.Menu
    end

    
    properties (Access = private)
        reprj_path % Description
    end
    
    methods (Access = private)
        function result = check_cell_value(app, input_cell)
            result = [];
            [cell_len, ~] = size(input_cell);
            for i=1:cell_len
                result = [result, isempty(input_cell{i})];
            end
        end
        
        function select_path = open_recent_prj_menu_callback(app, src, event)
            select_path = src.Text(4:end);
            disp(select_path);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if exist("config.mat", "file")
                config_data = load("config.mat", 'reprj_path');
                app.reprj_path = config_data.reprj_path;
            else
                app.reprj_path = cell(10, 1);
            end
            result = app.check_cell_value(app.reprj_path);
            new_menu = 10 - sum(result);
            for i=1:new_menu
                sub_menu = uimenu(app.OpenRecentProjectMenu,"Text", [num2str(i), '. ', app.reprj_path{i}]);
                sub_menu.MenuSelectedFcn = @app.open_recent_prj_menu_callback; 
            end
            
        end

        % Menu selected function: OpenProjectMenu
        function OpenProjectMenuSelected(app, event)
            current_path = pwd;
            select_path = uigetdir(current_path, "pick your folder");
            result = app.check_cell_value(app.reprj_path);
            for i = 1:10
                if result(i) == 1
                    app.reprj_path{i} = select_path;
                    break
                end
            end
            % app.reprj_path{} = [app.reprj_path; select_path];
            disp(app.reprj_path);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % msgbox("close this window.");
            is_close_app = false;
            result = uiconfirm(app.UIFigure, "Please confirm whether close this app","Close confirm", ...
                "Options", {'Yes', 'No'}, 'DefaultOption',2, 'CancelOption',2);
            switch result
                case 'Yes'
                    is_close_app = true;
                case 'No'
                    is_close_app = false;
            end
            if is_close_app
                reprj_path = app.reprj_path;
                save('config.mat', 'reprj_path');
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewProjectMenu = uimenu(app.FileMenu);
            app.NewProjectMenu.Text = 'New Project';
            app.OpenProjectMenu = uimenu(app.FileMenu);
            app.OpenProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenProjectMenuSelected, true);
            app.OpenProjectMenu.Text = 'Open Project';
            app.OpenRecentProjectMenu = uimenu(app.FileMenu);
            app.OpenRecentProjectMenu.Text = 'Open Recent Project';
            app.SaveProjectMenu = uimenu(app.FileMenu);
            app.SaveProjectMenu.Text = 'Save Project';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.Text = 'Save as ...';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = text_recent_prj

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end